classdef FittingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ImportDataTab                  matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        FolderFilesTab                 matlab.ui.container.Tab
        LoadAppstatewithalldataButton  matlab.ui.control.StateButton
        SaveAppStatewithalldataButton  matlab.ui.control.StateButton
        ImportselectedfilesButton      matlab.ui.control.StateButton
        FindfoldertoimportButton       matlab.ui.control.StateButton
        UITableFolderFiles             matlab.ui.control.Table
        ImportedDataTab                matlab.ui.container.Tab
        UITableImportedData            matlab.ui.control.Table
        FittingTab                     matlab.ui.container.Tab
        FittingTabGroup                matlab.ui.container.TabGroup
        KKFittingTab                   matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        KKFittingTabGroup              matlab.ui.container.TabGroup
        DataSelectionTab               matlab.ui.container.Tab
        LogNyquistCheckBox             matlab.ui.control.CheckBox
        ClearplotButton                matlab.ui.control.StateButton
        FitKKonselecteddataButton      matlab.ui.control.StateButton
        PlotSelectedDataButton         matlab.ui.control.StateButton
        UITableImportedData_2          matlab.ui.control.Table
        AdvancedSettingsTab_2          matlab.ui.container.Tab
        UIKKFittingTable               matlab.ui.control.Table
        UIAxesPhase                    matlab.ui.control.UIAxes
        UIAxesMod                      matlab.ui.control.UIAxes
        UIAxesError                    matlab.ui.control.UIAxes
        UIAxesNyquist                  matlab.ui.control.UIAxes
        KKfitvaluesTab                 matlab.ui.container.Tab
        FittingMethodDropDown          matlab.ui.control.DropDown
        FittingMethodDropDownLabel     matlab.ui.control.Label
        UIFitParameterTable            matlab.ui.control.Table
        UIAxesKKParameters             matlab.ui.control.UIAxes
        EquivilentCircuitFittingTab    matlab.ui.container.Tab
        CircuitSelectionTab            matlab.ui.container.Tab
        AdvancedSettingsTab            matlab.ui.container.Tab
    end

    
    properties (Access = public)
        Measurements = struct();
    end
    
    methods (Access = private)
        
        function ClearKKplots(app)
            % Create UIAxesNyquist
            delete(app.UIAxesNyquist)
            app.UIAxesNyquist = uiaxes(app.KKFittingTab);
            title(app.UIAxesNyquist, 'Nyquist')
            xlabel(app.UIAxesNyquist, 'Real Z')
            ylabel(app.UIAxesNyquist, '- Imaginary Z')
            zlabel(app.UIAxesNyquist, 'Z')
            app.UIAxesNyquist.XGrid = 'on';
            app.UIAxesNyquist.YGrid = 'on';
            app.UIAxesNyquist.Position = [13 540 395 238];
            h=plot(app.UIAxesNyquist,NaN,NaN);
            delete(h);

            % Create UIAxesError
            delete(app.UIAxesError)
            app.UIAxesError = uiaxes(app.KKFittingTab);
            title(app.UIAxesError, 'Error')
            xlabel(app.UIAxesError, 'Frequency')
            ylabel(app.UIAxesError, 'Residuals')
            zlabel(app.UIAxesError, 'Z')
            app.UIAxesError.XGrid = 'on';
            app.UIAxesError.YGrid = 'on';
            app.UIAxesError.Position = [426 540 395 238];
            h=plot(app.UIAxesError,NaN,NaN);
            delete(h);

            % Create UIAxesMod
            delete(app.UIAxesMod)
            app.UIAxesMod = uiaxes(app.KKFittingTab);
            title(app.UIAxesMod, 'Modulus')
            xlabel(app.UIAxesMod, 'Frequency (Hz)')
            ylabel(app.UIAxesMod, '| Z | (ohms)')
            zlabel(app.UIAxesMod, 'Z')
            app.UIAxesMod.XGrid = 'on';
            app.UIAxesMod.YGrid = 'on';
            app.UIAxesMod.Position = [13 285 395 238];
            h=plot(app.UIAxesMod,NaN,NaN);
            delete(h);

            % Create UIAxesPhase
            delete(app.UIAxesPhase)
            app.UIAxesPhase = uiaxes(app.KKFittingTab);
            title(app.UIAxesPhase, 'Phase')
            xlabel(app.UIAxesPhase, 'Frequency (Hz)')
            ylabel(app.UIAxesPhase, '\theta (degrees)')
            zlabel(app.UIAxesPhase, 'Z')
            app.UIAxesPhase.XGrid = 'on';
            app.UIAxesPhase.YGrid = 'on';
            app.UIAxesPhase.Position = [426 285 395 238];
            h=plot(app.UIAxesPhase,NaN,NaN);
            delete(h);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FindfoldertoimportButton
        function FindfoldertoimportButtonValueChanged(app, event)
            value = app.FindfoldertoimportButton.Value;
            if value
                % Select folder pop up
                importfolder=uigetdir(fileparts(matlab.desktop.editor.getActiveFilename),'Choose which folder of data to import');
                filelist = dir([importfolder '\*.dta']);
                for i = 1:length(filelist)
                    filename{i,1}= filelist(i).name(1:end-4);
                    folderpath{i,1} = filelist(i).folder;
                    fullpath{i,1} = fullfile(filelist(i).folder,filelist(i).name);
                    date{i,1}=datetime(filelist(i).date);
                end
                t=table(filename,date,folderpath,fullpath);
                % populate Table
                app.UITableFolderFiles.Data = t;
            end
            app.FindfoldertoimportButton.Value = 0;
        end

        % Value changed function: ImportselectedfilesButton
        function ImportselectedfilesButtonValueChanged(app, event)
            value = app.ImportselectedfilesButton.Value;
            if value
                % get selected/ highlighted rows
                if ~isempty(app.UITableFolderFiles.Selection)
                    % import those files to app.measurements\
                    currentlength = length(app.Measurements);
                    if ~isfield(app.Measurements(currentlength),'data') && currentlength == 1
                        currentlength = 0;
                    end
                    for i = 1:length(app.UITableFolderFiles.Selection)
                        app.Measurements(currentlength+i).data = importGamryDTAfile(app.UITableFolderFiles.Data.fullpath{app.UITableFolderFiles.Selection(i)});
                        app.Measurements(currentlength+i).filename = app.UITableFolderFiles.Data.filename{app.UITableFolderFiles.Selection(i)};
                        app.Measurements(currentlength+i).folderpath = app.UITableFolderFiles.Data.folderpath{app.UITableFolderFiles.Selection(i)};
                        app.Measurements(currentlength+i).fullpath = app.UITableFolderFiles.Data.fullpath{app.UITableFolderFiles.Selection(i)};
                        app.Measurements(currentlength+i).date = app.UITableFolderFiles.Data.date{app.UITableFolderFiles.Selection(i)};
                    end
                    % update imported data table
                    for i = 1:length(app.Measurements)
                        importedfilename{i,1} = app.Measurements(i).filename;
                        importeddate{i,1} = app.Measurements(i).date;
                        importedfolderpath{i,1} = app.Measurements(i).folderpath;
                        importedfullpath{i,1} = app.Measurements(i).fullpath;
                    end
                    t = table(importedfilename, importeddate, importedfolderpath,importedfullpath);
                    app.UITableImportedData.Data = t;
                    app.UITableImportedData_2.Data = t;
                else
                    disp('nothing selected to import')
                end
            end
            app.ImportselectedfilesButton.Value = 0;
        end

        % Selection changed function: UITableFolderFiles
        function UITableFolderFilesSelectionChanged(app, event)
            %app.ImportSelection = app.UITableFolderFiles.Selection;
            
        end

        % Value changed function: PlotSelectedDataButton
        function PlotSelectedDataButtonValueChanged(app, event)
            value = app.PlotSelectedDataButton.Value;
            if value
                % get selection
                sel=app.UITableImportedData_2.Selection;
                if ~isempty(sel)
                    % clear plots
                    app.ClearplotButton.Value = 1;
                    app.ClearplotButtonValueChanged
                    % check log-spaced nyquist button
                    if app.LogNyquistCheckBox.Value
                        % plot nyquist
                        loglog(app.UIAxesNyquist,app.Measurements(sel(1)).data.Zreal, -app.Measurements(sel(1)).data.Zimag, '.','DisplayName','Data');
                        hold(app.UIAxesNyquist,'on')
                        % plot fits if they exist
                        if isfield(app.Measurements(sel(1)), 'KKfit') && isstruct(app.Measurements(sel(1)).KKfit)
                            loglog(app.UIAxesNyquist,real(app.Measurements(sel(1)).KKfit.simplex.fitcurve),...
                                -imag(app.Measurements(sel(1)).KKfit.simplex.fitcurve), '-','DisplayName','Simplex');
                            loglog(app.UIAxesNyquist,real(app.Measurements(sel(1)).KKfit.levenbergMarquardt.fitcurve),...
                                -imag(app.Measurements(sel(1)).KKfit.levenbergMarquardt.fitcurve), '--','DisplayName','Levenberg-Marquardt');
                            loglog(app.UIAxesNyquist,real(app.Measurements(sel(1)).KKfit.trustRegion.fitcurve),...
                                -imag(app.Measurements(sel(1)).KKfit.trustRegion.fitcurve), '-.k','DisplayName','Trust-Region-Reflexive');
                        end
                    else
                        % plot nyquist
                        plot(app.UIAxesNyquist,app.Measurements(sel(1)).data.Zreal, -app.Measurements(sel(1)).data.Zimag, '.','DisplayName','Data');
                        hold(app.UIAxesNyquist,'on')
                        % plot fits if they exist
                        if isfield(app.Measurements(sel(1)), 'KKfit') && isstruct(app.Measurements(sel(1)).KKfit)
                            plot(app.UIAxesNyquist,real(app.Measurements(sel(1)).KKfit.simplex.fitcurve),...
                                -imag(app.Measurements(sel(1)).KKfit.simplex.fitcurve), '-','DisplayName','Simplex');
                            plot(app.UIAxesNyquist,real(app.Measurements(sel(1)).KKfit.levenbergMarquardt.fitcurve),...
                                -imag(app.Measurements(sel(1)).KKfit.levenbergMarquardt.fitcurve), '--','DisplayName','Levenberg-Marquardt');
                            plot(app.UIAxesNyquist,real(app.Measurements(sel(1)).KKfit.trustRegion.fitcurve),...
                                -imag(app.Measurements(sel(1)).KKfit.trustRegion.fitcurve), '-.k','DisplayName','Trust-Region-Reflexive');
                        end
                    end
                    hold(app.UIAxesNyquist,'off')
                    grid(app.UIAxesNyquist,'on')
                    xlabel(app.UIAxesNyquist,'Real(Z)');
                    ylabel(app.UIAxesNyquist,'-Imag(Z)');
                    legend(app.UIAxesNyquist,'Location','northeast');
                    
                    % plot Zmod
                    loglog(app.UIAxesMod,app.Measurements(sel(1)).data.Freq, app.Measurements(sel(1)).data.Zmod, '.','DisplayName','Data');
                    hold(app.UIAxesMod,'on')
                    % plot fits if they exist
                    if isfield(app.Measurements(sel(1)), 'KKfit') && isstruct(app.Measurements(sel(1)).KKfit)
                        loglog(app.UIAxesMod,app.Measurements(sel(1)).data.Freq, abs(app.Measurements(sel(1)).KKfit.simplex.fitcurve), '-','DisplayName','Simplex');
                        loglog(app.UIAxesMod,app.Measurements(sel(1)).data.Freq, abs(app.Measurements(sel(1)).KKfit.levenbergMarquardt.fitcurve), '--','DisplayName','Levenberg-Marquardt');
                        loglog(app.UIAxesMod,app.Measurements(sel(1)).data.Freq, abs(app.Measurements(sel(1)).KKfit.trustRegion.fitcurve), '-.k','DisplayName','Trust-Region-Reflexive');
                    end
                    hold(app.UIAxesMod,'off')
                    grid(app.UIAxesMod,'on')
                    xlim(app.UIAxesMod,[app.Measurements(sel(1)).data.Freq(end) app.Measurements(sel(1)).data.Freq(1)]);
                    xlabel(app.UIAxesMod,'Frequency (Hz)');
                    ylabel(app.UIAxesMod,'Impedance Magnitude |Z| (ohms)');
                    legend(app.UIAxesMod,'Location','northeast');

                    % plot Z phase
                    semilogx(app.UIAxesPhase,app.Measurements(sel(1)).data.Freq, app.Measurements(sel(1)).data.Zphz, '.','DisplayName','Data');
                    hold(app.UIAxesPhase,'on')
                    if isfield(app.Measurements(sel(1)), 'KKfit') && isstruct(app.Measurements(sel(1)).KKfit)
                        semilogx(app.UIAxesPhase,app.Measurements(sel(1)).data.Freq, 180/pi*angle(app.Measurements(sel(1)).KKfit.simplex.fitcurve), '-','DisplayName','Simplex');
                        semilogx(app.UIAxesPhase,app.Measurements(sel(1)).data.Freq, 180/pi*angle(app.Measurements(sel(1)).KKfit.levenbergMarquardt.fitcurve), '--','DisplayName','Levenberg-Marquardt');
                        semilogx(app.UIAxesPhase,app.Measurements(sel(1)).data.Freq, 180/pi*angle(app.Measurements(sel(1)).KKfit.trustRegion.fitcurve), '-.k','DisplayName','Trust-Region-Reflexive');
                    end
                    hold(app.UIAxesPhase,'off')
                    grid(app.UIAxesPhase,'on')
                    xlim(app.UIAxesPhase,[app.Measurements(sel(1)).data.Freq(end) app.Measurements(sel(1)).data.Freq(1)]);
                    xlabel(app.UIAxesPhase,'Frequency (Hz)');
                    ylabel(app.UIAxesPhase,'Phase (degrees)');
                    legend(app.UIAxesPhase,'Location','north');

                    % plot error
                    if isfield(app.Measurements(sel(1)), 'KKfit') && isstruct(app.Measurements(sel(1)).KKfit)
                        loglog(app.UIAxesError,app.Measurements(sel(1)).data.Freq, abs(app.Measurements(sel(1)).KKfit.simplex.residuals), '-','DisplayName','Simplex');
                        hold(app.UIAxesError,'on')
                        loglog(app.UIAxesError,app.Measurements(sel(1)).data.Freq, abs(app.Measurements(sel(1)).KKfit.levenbergMarquardt.residuals), '--','DisplayName','Levenberg-Marquardt');
                        loglog(app.UIAxesError,app.Measurements(sel(1)).data.Freq, abs(app.Measurements(sel(1)).KKfit.trustRegion.residuals), '-.k','DisplayName','Trust-Region-Reflexive');
                        hold(app.UIAxesError,'off')
                        grid(app.UIAxesError,'on')
                        xlim(app.UIAxesError,[app.Measurements(sel(1)).data.Freq(end) app.Measurements(sel(1)).data.Freq(1)]);
                        xlabel(app.UIAxesError,'Frequency (Hz)');
                        ylabel(app.UIAxesError,'Weighted Residuals');
                        legend(app.UIAxesError,'Location','northeast');
                    end
                    % plot KK fit values
                    if isfield(app.Measurements(sel(1)), 'KKfit') && isstruct(app.Measurements(sel(1)).KKfit)
                        l=length(app.Measurements(sel(1)).KKfit.v0);
                        scatter(app.UIAxesKKParameters,1:l, app.Measurements(sel(1)).KKfit.simplex.coeff, '.','DisplayName','Simplex');
                        hold(app.UIAxesKKParameters,'on')
                        scatter(app.UIAxesKKParameters,1:l, app.Measurements(sel(1)).KKfit.levenbergMarquardt.coeff, 'o','MarkerEdgeColor','r','DisplayName','Levenberg-Marquardt');
                        scatter(app.UIAxesKKParameters,1:l, app.Measurements(sel(1)).KKfit.trustRegion.coeff, 'd','MarkerEdgeColor','g','DisplayName','Trust-Region-Reflexive');
                        scatter(app.UIAxesKKParameters,1:l, app.Measurements(sel(1)).KKfit.v0, 'x','MarkerEdgeColor','k','DisplayName','Initial Guess')
                        hold(app.UIAxesKKParameters,'off')
                        grid(app.UIAxesKKParameters,'on')
                        set(app.UIAxesKKParameters, 'YScale', 'log');
                        
                        xlim(app.UIAxesKKParameters,[1 l]);
                        %xlabel(app.UIAxesError,'Frequency (Hz)');
                        %ylabel(app.UIAxesError,'Weighted Residuals');
                        legend(app.UIAxesKKParameters,'Location','northeast');
                    end
                    % update gof table
                    if isfield(app.Measurements(sel(1)), 'KKfit') && isstruct(app.Measurements(sel(1)).KKfit)
                        t = {};
                        t{1,1} = app.Measurements(sel(1)).KKfit.simplex.gof;
                        t{1,2} = app.Measurements(sel(1)).KKfit.simplex.R2;
                        t{1,3} = app.Measurements(sel(1)).KKfit.simplex.R2adjusted;
                        t{1,4} = 'Simplex';
                        t{2,1} = app.Measurements(sel(1)).KKfit.levenbergMarquardt.gof;
                        t{2,2} = app.Measurements(sel(1)).KKfit.levenbergMarquardt.R2;
                        t{2,3} = app.Measurements(sel(1)).KKfit.levenbergMarquardt.R2adjusted;
                        t{2,4} = 'Levenberg-Marquardt';
                        t{3,1} = app.Measurements(sel(1)).KKfit.trustRegion.gof;
                        t{3,2} = app.Measurements(sel(1)).KKfit.trustRegion.R2;
                        t{3,3} = app.Measurements(sel(1)).KKfit.trustRegion.R2adjusted;
                        t{3,4} = 'Trust-Region-Reflexive';
                        app.UIKKFittingTable.Data = t;
                    end
                end

            end
            app.PlotSelectedDataButton.Value = 0;
        end

        % Value changed function: FitKKonselecteddataButton
        function FitKKonselecteddataButtonValueChanged(app, event)
            value = app.FitKKonselecteddataButton.Value;
            if value
                % get selection
                sel=app.UITableImportedData_2.Selection;
                KKfitInputs = struct();
                if ~isempty(sel)
                    % fit KK
                    for i = 1:length(sel)
                        [KKfitInputs(i).func, KKfitInputs(i).x0, KKfitInputs(i).lb, KKfitInputs(i).ub, KKfitInputs(i).Variables] = ...
                            KKfunc(app.Measurements(sel(i)).data);
                        
                        app.Measurements(sel(i)).KKfit = fitZ( app.Measurements(sel(i)).data.Zreal + 1i.*app.Measurements(sel(i)).data.Zimag,...
                            app.Measurements(sel(i)).data.Freq, KKfitInputs(i).func, KKfitInputs(i).x0, KKfitInputs(i).lb, KKfitInputs(i).ub );

                        % extra fit interation using best fit parameters as
                        % guess
                        % find best fit coefficients
                        gof = [app.Measurements(sel(i)).KKfit.simplex.gof app.Measurements(sel(i)).KKfit.levenbergMarquardt.gof ...
                            app.Measurements(sel(i)).KKfit.trustRegion.gof];
                        params = {app.Measurements(sel(i)).KKfit.simplex.coeff, app.Measurements(sel(i)).KKfit.levenbergMarquardt.coeff, ...
                            app.Measurements(sel(i)).KKfit.trustRegion.coeff};
                        [~, ind] = sort(gof);
                        % do fit again
                        app.Measurements(sel(i)).KKfit = fitZ( app.Measurements(sel(i)).data.Zreal + 1i.*app.Measurements(sel(i)).data.Zimag,...
                            app.Measurements(sel(i)).data.Freq, KKfitInputs(i).func, params{ind(1)}, KKfitInputs(i).lb, KKfitInputs(i).ub );

                        % get names of variables
                        app.Measurements(sel(i)).KKfit.Variables = KKfitInputs(i).Variables;

                    end
                    % update plot
                    app.PlotSelectedDataButton.Value = 1;
                    app.PlotSelectedDataButtonValueChanged;
                end
            end
            app.FitKKonselecteddataButton.Value = 0;
        end

        % Value changed function: ClearplotButton
        function ClearplotButtonValueChanged(app, event)
            value = app.ClearplotButton.Value;
            if value
                ClearKKplots(app);
            end
            app.ClearplotButton.Value = 0;
        end

        % Value changed function: FittingMethodDropDown
        function FittingMethodDropDownValueChanged(app, event)
            value = app.FittingMethodDropDown.Value;
            sel=app.UITableImportedData_2.Selection;
            if ~isempty(sel) && isfield(app.Measurements(sel(1)), 'KKfit') && isstruct(app.Measurements(sel(1)).KKfit)
                M = length(app.Measurements(sel(1)).KKfit.Variables);
                t = cell(M,4);
                if strcmp(value, 'Initial Guess')
                    for j = 1:M
                        t{j,1} = app.Measurements(sel(1)).KKfit.Variables{j};
                        t{j,2} = app.Measurements(sel(1)).KKfit.v0(j);
                        t{j,4} = app.Measurements(sel(1)).KKfit.ub(j);
                        t{j,5} = app.Measurements(sel(1)).KKfit.lb(j);
                    end
                    app.UIFitParameterTable.Data = t;
                elseif strcmp(value, 'Simplex')
                    for j = 1:M
                        t{j,1} = app.Measurements(sel(1)).KKfit.Variables{j};
                        t{j,2} = app.Measurements(sel(1)).KKfit.simplex.coeff(j);
                        t{j,4} = app.Measurements(sel(1)).KKfit.ub(j);
                        t{j,5} = app.Measurements(sel(1)).KKfit.lb(j);
                    end
                    app.UIFitParameterTable.Data = t;
                elseif strcmp(value, 'Levenberg-Marquardt')
                    for j = 1:M
                        t{j,1} = app.Measurements(sel(1)).KKfit.Variables{j};
                        t{j,2} = app.Measurements(sel(1)).KKfit.levenbergMarquardt.coeff(j);
                        t{j,4} = app.Measurements(sel(1)).KKfit.ub(j);
                        t{j,5} = app.Measurements(sel(1)).KKfit.lb(j);
                    end
                    app.UIFitParameterTable.Data = t;
                elseif strcmp(value, 'Trust-Region-Reflective')
                    for j = 1:M
                        t{j,1} = app.Measurements(sel(1)).KKfit.Variables{j};
                        t{j,2} = app.Measurements(sel(1)).KKfit.trustRegion.coeff(j);
                        t{j,4} = app.Measurements(sel(1)).KKfit.ub(j);
                        t{j,5} = app.Measurements(sel(1)).KKfit.lb(j);
                    end
                    app.UIFitParameterTable.Data = t;
                end
            end
        end

        % Value changed function: LogNyquistCheckBox
        function LogNyquistCheckBoxValueChanged(app, event)
            value = app.LogNyquistCheckBox.Value;
            if value
                set(app.UIAxesNyquist,'YScale','log','XScale','log')
            else
                set(app.UIAxesNyquist,'YScale','linear','XScale','linear')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImportDataTab = uitab(app.TabGroup);
            app.ImportDataTab.Title = 'Import Data';
            app.TabGroup2 = uitabgroup(app.ImportDataTab);
            app.FolderFilesTab = uitab(app.TabGroup2);
            app.FolderFilesTab.Title = 'Folder Files';
            app.UITableFolderFiles = uitable(app.FolderFilesTab);
            app.UITableFolderFiles.ColumnName = {'Filename'; 'Date Created'; 'Folder path'; 'Full Path'};
            app.UITableFolderFiles.ColumnRearrangeable = 'on';
            app.UITableFolderFiles.RowName = {};
            app.UITableFolderFiles.ColumnSortable = [true true true false];
            app.UITableFolderFiles.SelectionType = 'row';
            app.UITableFolderFiles.SelectionChangedFcn = createCallbackFcn(app, @UITableFolderFilesSelectionChanged, true);
            app.FindfoldertoimportButton = uibutton(app.FolderFilesTab, 'state');
            app.FindfoldertoimportButton.ValueChangedFcn = createCallbackFcn(app, @FindfoldertoimportButtonValueChanged, true);
            app.FindfoldertoimportButton.Text = 'Find folder to import';
            app.ImportselectedfilesButton = uibutton(app.FolderFilesTab, 'state');
            app.ImportselectedfilesButton.ValueChangedFcn = createCallbackFcn(app, @ImportselectedfilesButtonValueChanged, true);
            app.ImportselectedfilesButton.Text = 'Import selected files';
            app.SaveAppStatewithalldataButton = uibutton(app.FolderFilesTab, 'state');
            app.SaveAppStatewithalldataButton.Text = 'Save App State with all data';
            app.LoadAppstatewithalldataButton = uibutton(app.FolderFilesTab, 'state');
            app.LoadAppstatewithalldataButton.Text = 'Load App state with all data';
            app.ImportedDataTab = uitab(app.TabGroup2);
            app.ImportedDataTab.Title = 'Imported Data';
            app.UITableImportedData = uitable(app.ImportedDataTab);
            app.UITableImportedData.ColumnName = {'Filename'; 'Date'; 'Lowest Frequency'; 'Vac'};
            app.UITableImportedData.RowName = {};
            app.UITableImportedData.ColumnSortable = true;
            app.UITableImportedData.SelectionType = 'row';
            app.UITableImportedData.ColumnEditable = [true false false false];
            app.FittingTab = uitab(app.TabGroup);
            app.FittingTab.Title = 'Fitting';
            app.FittingTabGroup = uitabgroup(app.FittingTab);
            app.KKFittingTab = uitab(app.FittingTabGroup);
            app.KKFittingTab.Title = 'KK Fitting';
            app.UIAxesNyquist = uiaxes(app.KKFittingTab);
            title(app.UIAxesNyquist, 'Nyquist')
            xlabel(app.UIAxesNyquist, 'Real Z')
            ylabel(app.UIAxesNyquist, '- Imaginary Z')
            zlabel(app.UIAxesNyquist, 'Z')
            app.UIAxesError = uiaxes(app.KKFittingTab);
            title(app.UIAxesError, 'Error')
            xlabel(app.UIAxesError, 'Frequency')
            ylabel(app.UIAxesError, 'Residuals')
            zlabel(app.UIAxesError, 'Z')
            app.UIAxesMod = uiaxes(app.KKFittingTab);
            title(app.UIAxesMod, 'Modulus')
            xlabel(app.UIAxesMod, 'Frequency (Hz)')
            ylabel(app.UIAxesMod, '| Z | (ohms)')
            zlabel(app.UIAxesMod, 'Z')
            app.UIAxesPhase = uiaxes(app.KKFittingTab);
            title(app.UIAxesPhase, 'Phase')
            xlabel(app.UIAxesPhase, 'Frequency (Hz)')
            ylabel(app.UIAxesPhase, '\theta (degrees)')
            zlabel(app.UIAxesPhase, 'Z')
            app.UIKKFittingTable = uitable(app.KKFittingTab);
            app.UIKKFittingTable.ColumnName = {'GoF'; 'R2'; 'R2Adjusted'; 'Method'};
            app.UIKKFittingTable.RowName = {};
            app.KKFittingTabGroup = uitabgroup(app.KKFittingTab);
            app.DataSelectionTab = uitab(app.KKFittingTabGroup);
            app.DataSelectionTab.Title = 'Data Selection';
            app.UITableImportedData_2 = uitable(app.DataSelectionTab);
            app.UITableImportedData_2.ColumnName = {'Filename'; 'Date'; 'Lowest Frequency'; 'Vac'};
            app.UITableImportedData_2.RowName = {};
            app.UITableImportedData_2.ColumnSortable = true;
            app.UITableImportedData_2.SelectionType = 'row';
            app.UITableImportedData_2.ColumnEditable = [true false false false];
            app.PlotSelectedDataButton = uibutton(app.DataSelectionTab, 'state');
            app.PlotSelectedDataButton.ValueChangedFcn = createCallbackFcn(app, @PlotSelectedDataButtonValueChanged, true);
            app.PlotSelectedDataButton.Text = 'Plot Selected Data';
            app.FitKKonselecteddataButton = uibutton(app.DataSelectionTab, 'state');
            app.FitKKonselecteddataButton.ValueChangedFcn = createCallbackFcn(app, @FitKKonselecteddataButtonValueChanged, true);
            app.FitKKonselecteddataButton.Text = 'Fit KK on selected data';
            app.ClearplotButton = uibutton(app.DataSelectionTab, 'state');
            app.ClearplotButton.ValueChangedFcn = createCallbackFcn(app, @ClearplotButtonValueChanged, true);
            app.ClearplotButton.Text = 'Clear plot';
            app.LogNyquistCheckBox = uicheckbox(app.DataSelectionTab);
            app.LogNyquistCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogNyquistCheckBoxValueChanged, true);
            app.LogNyquistCheckBox.Text = 'Log-spaced Nyquist';
            app.AdvancedSettingsTab_2 = uitab(app.KKFittingTabGroup);
            app.AdvancedSettingsTab_2.Title = 'Advanced Settings';
            app.UITable = uitable(app.KKFittingTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.KKfitvaluesTab = uitab(app.FittingTabGroup);
            app.KKfitvaluesTab.Title = 'KK fit values';
            app.UIAxesKKParameters = uiaxes(app.KKfitvaluesTab);
            title(app.UIAxesKKParameters, 'KK Parameter values')
            xlabel(app.UIAxesKKParameters, 'Parameter index')
            ylabel(app.UIAxesKKParameters, 'Value')
            zlabel(app.UIAxesKKParameters, 'Z')
            app.UIFitParameterTable = uitable(app.KKfitvaluesTab);
            app.UIFitParameterTable.ColumnName = {'Parameter'; 'Value'; 'Confidence Interval'; 'Upper Bound'; 'Lower Bound'};
            app.UIFitParameterTable.RowName = {};
            app.UIFitParameterTable.ColumnSortable = [true true false false false];
            app.FittingMethodDropDownLabel = uilabel(app.KKfitvaluesTab);
            app.FittingMethodDropDownLabel.Text = 'Fitting Method';
            app.FittingMethodDropDown = uidropdown(app.KKfitvaluesTab);
            app.FittingMethodDropDown.Items = {'Initial Guess', 'Simplex', 'Levenberg-Marquardt', 'Trust-Region-Reflective'};
            app.FittingMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @FittingMethodDropDownValueChanged, true);
            app.FittingMethodDropDown.Value = 'Initial Guess';
            app.EquivilentCircuitFittingTab = uitab(app.FittingTabGroup);
            app.EquivilentCircuitFittingTab.Title = 'Equivilent Circuit Fitting';
            app.CircuitSelectionTab = uitab(app.TabGroup);
            app.CircuitSelectionTab.Title = 'Circuit Selection';
            app.AdvancedSettingsTab = uitab(app.TabGroup);
            app.AdvancedSettingsTab.Title = 'Advanced Settings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FittingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end